classdef DetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Group13GlovesDefectDetectionSystemLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        LatexGloveTab                  matlab.ui.container.Tab
        BeginImageDetectionButton      matlab.ui.control.Button
        ImageFilenameEditField         matlab.ui.control.EditField
        ImageFilenameEditFieldLabel    matlab.ui.control.Label
        ImportImageButton              matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        CottonGloveTab                 matlab.ui.container.Tab
        BeginImageDetectionButton_2    matlab.ui.control.Button
        ImageFilenameEditField_2       matlab.ui.control.EditField
        ImageFilenameEditField_2Label  matlab.ui.control.Label
        ImportImageButton_2            matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
        NitrileGloveTab                matlab.ui.container.Tab
        DefectEditField                matlab.ui.control.EditField
        DefectEditFieldLabel           matlab.ui.control.Label
        ResultEditField                matlab.ui.control.EditField
        ResultLabel                    matlab.ui.control.Label
        BeginImageDetectionButton_3    matlab.ui.control.Button
        ImageFilenameEditField_3       matlab.ui.control.EditField
        ImageFilenameEditField_3Label  matlab.ui.control.Label
        ImportImageButton_3            matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        PVCNylonGloveTab               matlab.ui.container.Tab
        BeginImageDetectionButton_4    matlab.ui.control.Button
        ImageFilenameEditField_4       matlab.ui.control.EditField
        ImageFilenameEditField_4Label  matlab.ui.control.Label
        ImportImageButton_4            matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
    function loadAndDisplayImage(app, ax, fullname)
        img = imread(fullname);              % read image
        ax.UserData = img;                   % store for later use
        imshow(img, 'Parent', ax);           % show on UIAxes
    end

end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                % Read axes dimensions once at startup
        axesPos = app.UIAxes_2.Position;
        axesWidth  = axesPos(3);
        axesHeight = axesPos(4);

        % Display the values in the command window so you can see them
        fprintf('Axes Widthmine:  %d px\n', axesWidth);
        fprintf('Axes Heightmine: %d px\n', axesHeight);
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
    %             % Opens a file picker dialog filtered to common image formats.
    % % uigetfile returns the filename and the folder path separately.
    % [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tiff', ...
    %                            'Image Files (*.jpg,*.png,*.bmp,*.tiff)'}, ...
    %                            'Select a Glove Image');
    % 
    % % If the user pressed Cancel, file comes back as 0 — we check for that.
    % if isequal(file, 0)
    %     return;
    % end
    % 
    % % Put ONLY the filename into the edit field, as the user sees it
    % app.ImageFilenameEditField.Value = file;
    % 
    % % Combine folder path and filename into a full path string.
    % filename = fullfile(path, file);
    % app.loadAndDisplayImage(app.UIAxes_2, filename);


    [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_2, fullname);
        end

        % Button pushed function: BeginImageDetectionButton
        function BeginImageDetectionButtonPushed(app, event)
                img = app.UIAxes_2.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: ImportImageButton_2
        function ImportImageButton_2Pushed(app, event)
                [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_2.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_3, fullname);
        end

        % Button pushed function: BeginImageDetectionButton_2
        function BeginImageDetectionButton_2Pushed(app, event)
                            img = app.UIAxes_3.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: BeginImageDetectionButton_3
        function BeginImageDetectionButton_3Pushed(app, event)
            img = app.UIAxes_4.UserData;

            % Check if UserData is empty — means no image has been imported yet
            if isempty(img)
                uialert(app.UIFigure, 'Please import an image first.', 'No Image');
                return;
            end

            app. ResultEditField.Value = 'Loading...';
            app.ResultEditField.FontColor = [1 1 1]; % White
            drawnow; % Force UI to refresh immediately

            % Call defect detection function and display result
            [result, defect] = detectNitrileGloveDefect(img, app.UIAxes_4);

            % Update UI
            if strcmp(result, 'DEFECTIVE')
                app.ResultEditField.FontColor = [1 0 0];   % Red for defective
            elseif strcmp(result, 'NON-DEFECTIVE')
                app.ResultEditField.FontColor = [0 1 0]; % Green for non-defective
            end
            app. ResultEditField.Value = result;
            app. DefectEditField.Value = defect;
        end

        % Button pushed function: BeginImageDetectionButton_4
        function BeginImageDetectionButton_4Pushed(app, event)
                            img = app.UIAxes_5.UserData;

    % Check if UserData is empty — means no image has been imported yet
    if isempty(img)
        uialert(app.UIFigure, 'Please import an image first.', 'No Image');
        return;
    end
        end

        % Button pushed function: ImportImageButton_3
        function ImportImageButton_3Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
                            app.ResultEditField.Value = '';
                            app.DefectEditField.Value = '';
                            drawnow;

    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_3.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_4, fullname);
        end

        % Button pushed function: ImportImageButton_4
        function ImportImageButton_4Pushed(app, event)
                            [filename, path] = uigetfile({'*.jpeg;*.jpg;*.png;*.bmp;*.tiff', ...
                                   'Image Files'}, 'Select Glove Image');
    if isequal(filename, 0)
        return;
    end


    % Build full path
    fullname = fullfile(path, filename);

    % Derive clean name (no path, no extension) and update the text field here
    [~, fName, ~] = fileparts(fullname);
    app.ImageFilenameEditField_4.Value = fName;

    % Now just load and display the image
    app.loadAndDisplayImage(app.UIAxes_5, fullname);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LatexGloveTab = uitab(app.TabGroup);
            app.LatexGloveTab.Title = 'Latex Glove';
            app.UIAxes_2 = uiaxes(app.LatexGloveTab);
            app.ImportImageButton = uibutton(app.LatexGloveTab, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton.Text = 'Import Image';
            app.ImageFilenameEditFieldLabel = uilabel(app.LatexGloveTab);
            app.ImageFilenameEditFieldLabel.Text = 'Image Filename:';
            app.ImageFilenameEditField = uieditfield(app.LatexGloveTab, 'text');
            app.ImageFilenameEditField.Editable = 'off';
            app.ImageFilenameEditField.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton = uibutton(app.LatexGloveTab, 'push');
            app.BeginImageDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButtonPushed, true);
            app.BeginImageDetectionButton.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton.Text = 'Begin Image Detection';
            app.CottonGloveTab = uitab(app.TabGroup);
            app.CottonGloveTab.Title = 'Cotton Glove';
            app.UIAxes_3 = uiaxes(app.CottonGloveTab);
            app.ImportImageButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.ImportImageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_2Pushed, true);
            app.ImportImageButton_2.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_2.Text = 'Import Image';
            app.ImageFilenameEditField_2Label = uilabel(app.CottonGloveTab);
            app.ImageFilenameEditField_2Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_2 = uieditfield(app.CottonGloveTab, 'text');
            app.ImageFilenameEditField_2.Editable = 'off';
            app.ImageFilenameEditField_2.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_2 = uibutton(app.CottonGloveTab, 'push');
            app.BeginImageDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_2Pushed, true);
            app.BeginImageDetectionButton_2.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_2.Text = 'Begin Image Detection';
            app.NitrileGloveTab = uitab(app.TabGroup);
            app.NitrileGloveTab.Title = 'Nitrile Glove';
            app.UIAxes_4 = uiaxes(app.NitrileGloveTab);
            app.ImportImageButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.ImportImageButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_3Pushed, true);
            app.ImportImageButton_3.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_3.Text = 'Import Image';
            app.ImageFilenameEditField_3Label = uilabel(app.NitrileGloveTab);
            app.ImageFilenameEditField_3Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_3 = uieditfield(app.NitrileGloveTab, 'text');
            app.ImageFilenameEditField_3.Editable = 'off';
            app.ImageFilenameEditField_3.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_3 = uibutton(app.NitrileGloveTab, 'push');
            app.BeginImageDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_3Pushed, true);
            app.BeginImageDetectionButton_3.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_3.Text = 'Begin Image Detection';
            app.ResultLabel = uilabel(app.NitrileGloveTab);
            app.ResultLabel.Text = 'Result:';
            app.ResultEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.DefectEditFieldLabel = uilabel(app.NitrileGloveTab);
            app.DefectEditFieldLabel.Text = 'Defect:';
            app.DefectEditField = uieditfield(app.NitrileGloveTab, 'text');
            app.PVCNylonGloveTab = uitab(app.TabGroup);
            app.PVCNylonGloveTab.Title = 'PVC/Nylon Glove';
            app.UIAxes_5 = uiaxes(app.PVCNylonGloveTab);
            app.ImportImageButton_4 = uibutton(app.PVCNylonGloveTab, 'push');
            app.ImportImageButton_4.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButton_4Pushed, true);
            app.ImportImageButton_4.Tooltip = {'Click this to import an Image from your local storage'};
            app.ImportImageButton_4.Text = 'Import Image';
            app.ImageFilenameEditField_4Label = uilabel(app.PVCNylonGloveTab);
            app.ImageFilenameEditField_4Label.Text = 'Image Filename:';
            app.ImageFilenameEditField_4 = uieditfield(app.PVCNylonGloveTab, 'text');
            app.ImageFilenameEditField_4.Editable = 'off';
            app.ImageFilenameEditField_4.Tooltip = {'The Filename of your image will be shown here'};
            app.BeginImageDetectionButton_4 = uibutton(app.PVCNylonGloveTab, 'push');
            app.BeginImageDetectionButton_4.ButtonPushedFcn = createCallbackFcn(app, @BeginImageDetectionButton_4Pushed, true);
            app.BeginImageDetectionButton_4.Tooltip = {'Click this to start detecting defects on your glove image'};
            app.BeginImageDetectionButton_4.Text = 'Begin Image Detection';
            app.Group13GlovesDefectDetectionSystemLabel = uilabel(app.UIFigure);
            app.Group13GlovesDefectDetectionSystemLabel.Text = 'Group 13 Gloves Defect Detection System';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end